classdef guiApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RunSimulationButton             matlab.ui.control.Button
        Lamp                            matlab.ui.control.Lamp
        UIAxes_PowerCurve               matlab.ui.control.UIAxes
        AbortSimulationButton           matlab.ui.control.Button
        NACA0012asReferenceAirfoilPanel  matlab.ui.container.Panel
        CordLengthEditFieldLabel        matlab.ui.control.Label
        CordLengthEditField             matlab.ui.control.NumericEditField
        ReynoldsNumberEditField_2Label  matlab.ui.control.Label
        ReynoldsNumberEditField_NacaReference  matlab.ui.control.NumericEditField
        NACAReference_AddAirfoilButton  matlab.ui.control.Button
        MediumButtonGroup_NacaReference  matlab.ui.container.ButtonGroup
        WaterButton_NacaReference       matlab.ui.control.RadioButton
        AirButton_NacaReference         matlab.ui.control.RadioButton
        CustomProfilePanel              matlab.ui.container.Panel
        FilePathEditFieldLabel          matlab.ui.control.Label
        FilePathEditField_CustomProfile  matlab.ui.control.EditField
        CordLengthEditField_3Label      matlab.ui.control.Label
        CordLengthEditField_CustomProfile  matlab.ui.control.NumericEditField
        LoadProfileButton               matlab.ui.control.Button
        UIAxes_CustomProfile            matlab.ui.control.UIAxes
        MediumButtonGroup_CustomProfile  matlab.ui.container.ButtonGroup
        WaterButton_CustomProfile       matlab.ui.control.RadioButton
        AirButton_CustomProfile         matlab.ui.control.RadioButton
        AddAirfoilButton_CustomProfile  matlab.ui.control.Button
        ReynoldsNumberLabel_2           matlab.ui.control.Label
        ReynoldsNumberEditField_CustomAirfoil  matlab.ui.control.NumericEditField
        DeleteAirfoilButton_CustomProfile  matlab.ui.control.Button
        StandardAirfoilPanel            matlab.ui.container.Panel
        DropDown_StandardAirfoil        matlab.ui.control.DropDown
        CordLengthEditField_4Label      matlab.ui.control.Label
        CordLengthEditField_StandardAirfoil  matlab.ui.control.NumericEditField
        MediumButtonGroup_StandardAirfoil  matlab.ui.container.ButtonGroup
        WaterButton_StandardAirfoil     matlab.ui.control.RadioButton
        AirButton_StandardAirfoil       matlab.ui.control.RadioButton
        AirfoiltypeLabel                matlab.ui.control.Label
        StandardAirfoil_AddAirfoilButton  matlab.ui.control.Button
        ReynoldsNumberLabel             matlab.ui.control.Label
        ReynoldsNumberEditField_StandardAirfoil  matlab.ui.control.NumericEditField
        ProfilePowerCurvesComparerLabel  matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        DeleteElementButton             matlab.ui.control.Button
        ClearTableButton                matlab.ui.control.Button
        HidecurveButton                 matlab.ui.control.Button
        PlotcurveButton                 matlab.ui.control.Button
        StatusEditFieldLabel            matlab.ui.control.Label
        StatusEditField                 matlab.ui.control.EditField
    end

    
    properties (Access = public)
        m_tableData; % variable holding the state of the table
        m_currentlySelectedRow = -1;
        m_DialogApp; % Dialog box app
        
        % Custom Profile Data %
        m_profileName;
        m_coordinatesPath;
        m_filename;
        
        m_xCoord = [];
        m_yCoord = [];
    end
    
    methods (Access = private)
        
        function [xCoord, yCoord] = runSimulation(app, t_textFile, t_airfoilCordLength, t_ReynoldsNumber, t_density)
            
            ChordLenght = t_airfoilCordLength;
            CyKloneFactor1 = 1.5;
            CyKloneFactor2 = 0.05;
            TextFile = t_textFile;
            ReynoldsNumber = t_ReynoldsNumber;
            ro = str2num(t_density);
            
            r=1;
            c=1;
            AbsoluteWaterSpeedVA = zeros(361,3);
            AbsoluteWaterSpeed = 1;
            AbsoluteWaterSpeedV = AbsoluteWaterSpeed*[0 -1 0];                                            %This vector is arbitrary fixed
            for i=1:1:360                                                               %This loop makes a list of vectors to simplify further calculations
                AbsoluteWaterSpeedVA(i,:) =AbsoluteWaterSpeedV;
            end
            i = 1;
            Height = 1;
            BladesNumber = 3;
            Radius = 1;
            
            n = 1/CyKloneFactor2;
            [Cl_interpol,Cd_interpol]=getCoefficientsFromTxtFast(TextFile, ReynoldsNumber);
            
            
            for AngularSpeed =1.5:0.5:20
                
                AngularSpeedV = AngularSpeed*[0 0 1];                                       % Transforms the angular speed to a vector for further calculations
                [RelativeWaterSpeedM,PeripheralSpeedVA,RadiusVA,RelativeWaterSpeedVA,AngleOfAttackA,Theta]=vectorcalculator(Radius,AngularSpeedV,AbsoluteWaterSpeedV); %Uses the funtion made to get the values for more info use comand 'help'
                
                
                RelativeWaterSpeedM2 = RelativeWaterSpeedM.*RelativeWaterSpeedM;
                DynamicPreasure = 0.5*ro*RelativeWaterSpeedM2;
                [LiftCoefficient,DragCoefficient]= readingofcoefficientsFast(AngleOfAttackA,Cl_interpol,Cd_interpol);
                %     figure(1);
                %        plot(AngleOfAttackA(:,1));
                %         hold on
                for i = 0:1:n
                    ChordLenghtV=ChordLenght+i*CyKloneFactor2*(CyKloneFactor1*ChordLenght-ChordLenght);
                    [Power,Moment,TotalForce,TangentialForce,NormalForce,Ct,Cn] = dynamiccalculator(ReynoldsNumber,ro,AngleOfAttackA,LiftCoefficient,DragCoefficient,RelativeWaterSpeedM2,Height,ChordLenght,Theta,Radius,AngularSpeed);
                    Theta = 0:1:360;
                    Theta = deg2rad(Theta);
                    
                    T =(AngularSpeed*Radius/AbsoluteWaterSpeed);
                    P = ((cos(Theta)+T).^2 + sin(Theta).^2);
                    Q = (Cn'.*sin(Theta)-Ct'.*cos(Theta));
                    J =(P.*Q);
                    G = (BladesNumber*ChordLenghtV)/(16*180*Radius)*trapz(J);
                    
                    k = (1-G)/(1+G);
                    TotalPower = Power(:,1)+Power(:,2)+Power(:,3);
                    TotalPowerM = (trapz(TotalPower))*2*pi/360.0;
                    
                    TotalRelativeWaterSpeedM = (trapz(RelativeWaterSpeedM(:,1)))*2*pi/360.0;
                    PowerExp = 0.5*ro*Radius*Height*2*(2*AbsoluteWaterSpeed/(1+k))^3;
                    Cp = (BladesNumber*ChordLenghtV)/(32*180*Radius)*trapz(Ct'.*((P*AngularSpeed*Radius/AbsoluteWaterSpeed)*(1+k)^3));
                    NewTipSpeed = AngularSpeed*Radius/AbsoluteWaterSpeed*((1+k)/2);
                    Iterations(r,c)=NewTipSpeed;
                    Iterations(r,c+1)=Cp;
                    c=c+2;
                end
                %     CpT(i,:)=[NewTipSpeed Cp];
                %     Checker(i,:)=[G k];
                r=r+1;
                c=1;
            end
            %hold off
            
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%5
            %for j=1:2:2*(n+1)
            %    figure(1);
            %    plot(Iterations(:,j),Iterations(:,j+1));
            %    hold on
            %end
            %hold off
            %title("Power Curves CyKlone Variation")
            %xlabel("Tip Speed Ratio \lambda ");
            %ylabel("Power Coefficient Cp");
            %%%%%%%%%%TotalCurvePlot%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            FinalCurve = zeros(r-1,2);
            %figure(2)
            [M,N]=max(Iterations(:,2*(n+1)));
            FinalCurve = Iterations(1:N,2*(n+1)-1:2*(n+1));
            for j=2*(n+1)-2:-2:3
                [M,I]=max(Iterations(:,j));
                FinalCurve = [FinalCurve;Iterations(I,j-1:j)];
            end
            [M,N]=max(Iterations(:,2));
            FinalCurve=[FinalCurve;Iterations(I:r-1,1:2)];
            
            %plot(FinalCurve(:,1),FinalCurve(:,2));
            %title("Power Curve in the given range");
            %xlabel("Tip Speed Ratio \lambda ");
            %ylabel("Power Coefficient Cp");
            
            xCoord = FinalCurve(:, 1);
            yCoord = FinalCurve(:, 2);
        end
        
        function canSimulationStart = isDataValid(app, cordLength)
            reynoldsNum = app.ReynoldsNumberEditField.Value;
            cyklonePath = app.CykloneDataPathEditField.Value;
            
            canSimulationStart = true;
            
            if (cordLength <= 0 || reynoldsNum <= 0 || cyklonePath == "")
                app.StatusEditField.FontColor = 'red';
                app.StatusEditField.Value = 'Incorrect Data!';
                app.Lamp.Color = 'red';
                
                pause(0.3);
                
                app.StatusEditField.Value = '';
                app.Lamp.Color = '#D3D3D3';
                canSimulationStart = false;
                return;
            end
            
            darkGreenColor = [0.1660 0.5740 0.1880];
            app.StatusEditField.FontColor = darkGreenColor;
            app.StatusEditField.Value = 'Simulation started!';
            app.Lamp.Color = 'green';
            
            pause(0.3);
            app.StatusEditField.Value = '';
            app.Lamp.Color = '#D3D3D3';
            
            canSimulationStart = true;
        end
        
        function validateAirfoil_1(app)
            
            disp('Validation of first airfoil ...');
            
            canSimulationStart = app.isDataValid(app.CordLengthEditField.Value);
            if(canSimulationStart ~= true)
                return;
            end
            
            app.runSimulation(app.CordLengthEditField.Value);
            
            
        end
        
        function validateAirfoil_2(app)
            
            disp('Validation of second airfoil ...');
            
            canSimulationStart = app.isDataValid(app.CordLengthEditField_StandardAirfoil.Value);
            if(canSimulationStart ~= true)
                return;
            end
            
            app.runSimulation(app.CordLengthEditField_StandardAirfoil.Value);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function AnalyzeFirstButtonPushed(app, event)
            filename = app.FirstFilenameEditField.Value;
            
            if(filename == "")
                app.StatusEditField.Value = '';
                return;
            end
            
            fileID = fopen(filename);
            if(fileID < 0)
                app.StatusEditField.Value = 'File Not Found!';
                return;
            end
            
            app.parseFile(fileID);
            
        end

        % Callback function
        function AnalyzeSecondButtonPushed(app, event)
            filename = app.SecFilenameEditField.Value;
            
            if(filename == "")
                app.StatusEditField_2.Value = '';
                return;
            end
            
            fileID = fopen(filename);
            if(fileID < 0)
                app.StatusEditField_2.Value = 'File Not Found!';
                return;
            end
        end

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
            
            if (length(app.m_tableData) == 0)
                disp('No data in the table!');
                return;
            end
            
            app.UIAxes_PowerCurve.cla;
            sz = size(app.m_tableData);
            nrElements = sz(1,1)
            
            for k = 1:1:nrElements
                app.m_tableData
                
                cordLength = app.m_tableData{k,3};
                reynolds   = app.m_tableData{k,4};
                medium     = app.m_tableData{k,5};
                
                if(strcmp(medium,'Water') == true)
                    density       = '1000';
                    viscosity     = '0.0000013053';
                    speedOfSound  = '1447';
                elseif (strcmp(medium, 'Air') == true)
                    density       = '1.225';
                    viscosity     = '0.000014607';
                    speedOfSound  = '340';
                end
                
                % Create filename for the custom profile data
                app.m_coordinatesPath(k)
                profileCoordinatesPath   =  app.m_coordinatesPath(k);
                
                profileName = app.m_tableData{k, 2};
                filename = app.m_filename{k};
                saveToFile = sprintf('%s\\JavafoilData\\%s.txt', pwd, filename);
                disp(profileCoordinatesPath);
                
                fileID = fopen("dataStorageFile.txt", 'w');
                profileCoordinatesPath = char(profileCoordinatesPath);
                density
                viscosity
                speedOfSound
                reynolds
                saveToFile
                fprintf(fileID, '%s\n%s\n%s\n%s\n%s\n%s', profileCoordinatesPath, density, viscosity, speedOfSound, reynolds, saveToFile);
                
                mhclassesPath = 'C:\\Program Files (x86)\\MH AeroTools\\JavaFoil\\java\\mhclasses.jar';
                javafoilPath  = 'C:\\Program Files (x86)\\MH AeroTools\\JavaFoil\\java\\javafoil.jar';
                scriptPath    = '.\Scripts\loadCustomProfile.jfscript';
                command        = sprintf('java.exe -DScript="%s" -cp "%s" -cp "%s" MH.JavaFoil.JavaFoil', scriptPath, mhclassesPath, javafoilPath);
                
                [status,cmdout] = system(command);
                
                [xCoord, yCoord] = app.runSimulation(saveToFile, cordLength, reynolds, density);
                
                
                if (k == 1)
                    color = '-r';
                elseif (k == 2)
                    color = '-g';
                elseif(k == 3)
                    color = '-b';
                elseif(k == 4)
                    color = '--r';
                elseif(k == 5)
                    color = '--g';
                else
                    color = '--b';
                end
                hold(app.UIAxes_PowerCurve);
                plot(app.UIAxes_PowerCurve, xCoord, yCoord, color);
                hold(app.UIAxes_PowerCurve);
                
            end
            
        end

        % Button pushed function: LoadProfileButton
        function LoadProfileButtonPushed(app, event)
            if(app.FilePathEditField_CustomProfile.Value == "")
                errorMessage = 'Filepath is empty';
                app.m_DialogApp = errorMessageBox(errorMessage);
                return;
            end
            
            % Create filename for the custom profile data
            filename   = app.FilePathEditField_CustomProfile.Value;
            profileCoordinatesPath   = sprintf('%s\\Profiles\\%s', pwd ,filename);
            disp(profileCoordinatesPath);
            
            % Check if file exists
            fileID = fopen(profileCoordinatesPath);
            if (fileID < 0)
                errorMessage = 'File does not exist';
                app.m_DialogApp = errorMessageBox(errorMessage);
                return;
            end
            
            %% count lines in the file
            nrLines = 0;
            while true
                tline = fgetl(fileID);
                if(tline == -1)
                    frewind(fileID);
                    break;
                end
                nrLines = nrLines + 1;
            end
            %% fill in the vector with file's lines
            lines = strings(nrLines, 1);
            for i = 1: 1: nrLines
                tline = fgetl(fileID);
                
                if(tline == -1)
                    fclose(fileID);
                    break;
                end
                
                if (tline ~= "")
                    lines(i) = tline;
                end
            end
            
            % Parse the file
            coordinates = cell(2, length(lines));
            cellIndex = 1;
            
            for i = 1:1: length(lines)
                lines(i) = replace(lines(i), ',', '.');
                newStr = split(lines(i));
                if (length(newStr) == 2 && i ~= 1)
                    coordinates(1, cellIndex) = num2cell(str2double(newStr(1)));
                    coordinates(2, cellIndex) = num2cell(str2double(newStr(2)));
                    cellIndex = cellIndex + 1;
                end
            end
            
            % Put data onto the screen
            plotTitle = lines(1);
            app.m_profileName = convertStringsToChars(plotTitle);
            plotCoordinates = cell2mat(coordinates);
            app.m_xCoord = plotCoordinates(1, :);
            app.m_yCoord = plotCoordinates(2, :);
            
            plot(app.UIAxes_CustomProfile, app.m_xCoord, app.m_yCoord);
            app.UIAxes_CustomProfile.Title.String = plotTitle;
            
            % Create Data Storage File to be used in the script
            fileID = fopen('dataStorageFile.txt', 'w');
            if (fileID < 0)
                errorMessage = 'Could not create dataStorageFile';
                app.m_DialogApp = errorMessageBox(errorMessage);
                return;
            end
            
            app.AddAirfoilButton_CustomProfile.Enable = true;
        end

        % Button pushed function: NACAReference_AddAirfoilButton
        function NACAReference_AddAirfoilButtonPushed(app, event)
            newTableRow    = {};
            cordLength     = app.CordLengthEditField.Value;
            reynoldsNumber = app.ReynoldsNumberEditField_NacaReference.Value;
            mediumWater    = app.WaterButton_NacaReference.Value;
            mediumAir      = app.AirButton_NacaReference.Value;
            
            if(cordLength <= 0)
                errorMessage = 'Cord length smaller than 0';
                app.m_DialogApp = errorMessageBox(errorMessage);
                return;
            end
            
            if(reynoldsNumber <= 0)
                errorMessage = 'Reynolds Number smaller than 0';
                app.m_DialogApp = errorMessageBox(app, errorMessage);
                return;
            end
            
            if (mediumWater == true)
                medium = 'Water';
            elseif (mediumAir == true)
                medium = 'Air';
            end
            
            tableSize = size(app.m_tableData);
            rowID = tableSize(1,1) + 1;
            
            newTableRow = {rowID 'NACA 0012' cordLength reynoldsNumber medium};
            app.m_tableData = [app.m_tableData; newTableRow];
            
            app.UITable.Data = app.m_tableData;
            
            app.m_coordinatesPath{end + 1} = sprintf('%s\\profiles\\NACA0012.txt', pwd);
            app.m_filename{end + 1} = 'NACA0012';
        end

        % Button pushed function: ClearTableButton
        function ClearTableButtonPushed(app, event)
            app.m_tableData  = {};
            app.UITable.Data = app.m_tableData;
            app.m_coordinatesPath = {};
        end

        % Button pushed function: DeleteElementButton
        function DeleteElementButtonPushed(app, event)
            if(app.m_currentlySelectedRow == -1)
                return;
            end
            
            tableSize = size(app.m_tableData);
            if (tableSize(1,1) == 0)
                return;
            end
            
            if (app.m_currentlySelectedRow > tableSize(1,1))
                return;
            end
            
            app.m_currentlySelectedRow;
            app.m_coordinatesPath(app.m_currentlySelectedRow) = [];
            app.m_tableData(app.m_currentlySelectedRow,:) = [];
            app.UITable.Data = app.m_tableData;
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            app.m_currentlySelectedRow = indices(1,1);
        end

        % Button pushed function: StandardAirfoil_AddAirfoilButton
        function StandardAirfoil_AddAirfoilButtonPushed(app, event)
            newTableRow    = {};
            airfoilType    = app.DropDown_StandardAirfoil.Value
            cordLength     = app.CordLengthEditField_StandardAirfoil.Value;
            reynoldsNumber = app.ReynoldsNumberEditField_StandardAirfoil.Value;
            mediumWater    = app.WaterButton_StandardAirfoil.Value;
            mediumAir      = app.AirButton_StandardAirfoil.Value;
            
            if(cordLength <= 0)
                errorMessage = 'Cord length smaller than 0';
                app.m_DialogApp = errorMessageBox(errorMessage);
                return;
            end
            
            if(reynoldsNumber <= 0)
                errorMessage = 'Reynolds Number smaller than 0';
                app.m_DialogApp = errorMessageBox(app, errorMessage);
                return;
            end
            
            if (mediumWater == true)
                medium = 'Water';
            elseif (mediumAir == true)
                medium = 'Air';
            end
            
            tableSize = size(app.m_tableData);
            rowID = tableSize(1,1) + 1;
            
            newTableRow = {rowID airfoilType cordLength reynoldsNumber medium};
            app.m_tableData = [app.m_tableData; newTableRow];
            
            app.UITable.Data = app.m_tableData;
            
            if (app.DropDown_StandardAirfoil.Value == 'NACA 0015')
                app.m_filename{end + 1} = 'NACA0015';
                app.m_coordinatesPath{end + 1} = sprintf('%s\\profiles\\NACA0015.txt', pwd);
            elseif (app.DropDown_StandardAirfoil.Value == 'NACA 0018')
                app.m_filename{end + 1} = 'NACA0018';
                app.m_coordinatesPath{end + 1} = sprintf('%s\\profiles\\NACA0018.txt', pwd);
            elseif (app.DropDown_StandardAirfoil.Value == 'NACA 2412')
                app.m_filename{end + 1} = 'NACA2412';
                app.m_coordinatesPath{end + 1} = sprintf('%s\\profiles\\NACA2412.txt', pwd);
            end
            
        end

        % Button pushed function: AddAirfoilButton_CustomProfile
        function AddAirfoilButton_CustomProfilePushed(app, event)
            if( isequal( app.m_xCoord, []) || isequal(app.m_yCoord, []))
                return;
            end
            
            newTableRow    = {};
            airfoilType    = app.m_profileName;
            cordLength     = app.CordLengthEditField_CustomProfile.Value;
            reynoldsNumber = app.ReynoldsNumberEditField_CustomAirfoil.Value;
            mediumWater    = app.WaterButton_CustomProfile.Value;
            mediumAir      = app.AirButton_CustomProfile.Value;
            
            if(cordLength <= 0)
                errorMessage = 'Cord length smaller than 0';
                app.m_DialogApp = errorMessageBox(errorMessage);
                return;
            end
            
            if(reynoldsNumber <= 0)
                errorMessage = 'Reynolds Number smaller than 0';
                app.m_DialogApp = errorMessageBox(app, errorMessage);
                return;
            end
            
            if (mediumWater == true)
                medium = 'Water';
            elseif (mediumAir == true)
                medium = 'Air';
            end
            
            tableSize = size(app.m_tableData);
            rowID = tableSize(1,1) + 1;
            
            newTableRow = {rowID airfoilType cordLength reynoldsNumber medium}
            app.m_tableData = [app.m_tableData; newTableRow];
            
            app.UITable.Data = app.m_tableData;
            
            % Create Data Storage File to be used in the script
            fileID = fopen('dataStorageFile.txt', 'w');
            if (fileID < 0)
                disp('Storage file is not present!');
                return;
            end
            
            if (app.WaterButton_CustomProfile.Value == true)
                density       = '1000';
                viscosity     = '0.0000013053';
                speedOfSound  = '1447';
            elseif (app.AirButton_CustomProfile.Value == true)
                density       = '1.225';
                viscosity     = '0.000014607';
                speedOfSound  = '340';
            end
            
            filename = app.FilePathEditField_CustomProfile.Value;
            app.m_coordinatesPath{end + 1} = sprintf('%s\\profiles\\%s', pwd, filename);
            app.m_filename{end + 1} = filename;
            
            % Create filename for the custom profile data
            %filename   = app.FilePathEditField_CustomProfile.Value;
            %profileCoordinatesPath   = sprintf('%s\\Profiles\\%s', pwd ,filename);
            %saveToFile = sprintf('%s\\JavafoilData\\%s', pwd, filename);
            %disp(profileCoordinatesPath);
            
            
            %fprintf(fileID, '%s\n%s\n%s\n%s\n%s\n%s', profileCoordinatesPath, density, viscosity, speedOfSound, reynoldsNumber, saveToFile);
            
            %mhclassesPath = 'C:\\Program Files (x86)\\MH AeroTools\\JavaFoil\\java\\mhclasses.jar';
            %javafoilPath  = 'C:\\Program Files (x86)\\MH AeroTools\\JavaFoil\\java\\javafoil.jar';
            %scriptPath    = '.\Scripts\loadCustomProfile.jfscript';
            %command        = sprintf('java.exe -DScript="%s" -cp "%s" -cp "%s" MH.JavaFoil.JavaFoil &', scriptPath, mhclassesPath, javafoilPath);
            
            %system(command, '-echo');
        end

        % Button pushed function: DeleteAirfoilButton_CustomProfile
        function DeleteAirfoilButton_CustomProfilePushed(app, event)
            app.UIAxes_CustomProfile.cla;
            app.UIAxes_CustomProfile.Title.String = 'Custom Profile';
            app.m_profileName = '';
            app.m_xCoord = [];
            app.m_yCoord = [];
            app.AddAirfoilButton_CustomProfile.Enable = false;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.RunSimulationButton = uibutton(app.UIFigure, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.Lamp = uilamp(app.UIFigure);
            app.UIAxes_PowerCurve = uiaxes(app.UIFigure);
            title(app.UIAxes_PowerCurve, 'Power Curve')
            xlabel(app.UIAxes_PowerCurve, 'X')
            ylabel(app.UIAxes_PowerCurve, 'Y')
            app.AbortSimulationButton = uibutton(app.UIFigure, 'push');
            app.AbortSimulationButton.Text = 'Abort Simulation';
            app.NACA0012asReferenceAirfoilPanel = uipanel(app.UIFigure);
            app.NACA0012asReferenceAirfoilPanel.Title = 'NACA 0012 as Reference Airfoil';
            app.CordLengthEditFieldLabel = uilabel(app.NACA0012asReferenceAirfoilPanel);
            app.CordLengthEditFieldLabel.Text = 'Cord Length';
            app.CordLengthEditField = uieditfield(app.NACA0012asReferenceAirfoilPanel, 'numeric');
            app.ReynoldsNumberEditField_2Label = uilabel(app.NACA0012asReferenceAirfoilPanel);
            app.ReynoldsNumberEditField_2Label.Text = 'Reynolds Number';
            app.ReynoldsNumberEditField_NacaReference = uieditfield(app.NACA0012asReferenceAirfoilPanel, 'numeric');
            app.NACAReference_AddAirfoilButton = uibutton(app.NACA0012asReferenceAirfoilPanel, 'push');
            app.NACAReference_AddAirfoilButton.ButtonPushedFcn = createCallbackFcn(app, @NACAReference_AddAirfoilButtonPushed, true);
            app.NACAReference_AddAirfoilButton.Text = 'Add Airfoil';
            app.MediumButtonGroup_NacaReference = uibuttongroup(app.NACA0012asReferenceAirfoilPanel);
            app.MediumButtonGroup_NacaReference.Title = 'Medium';
            app.WaterButton_NacaReference = uiradiobutton(app.MediumButtonGroup_NacaReference);
            app.WaterButton_NacaReference.Text = 'Water';
            app.WaterButton_NacaReference.Value = true;
            app.AirButton_NacaReference = uiradiobutton(app.MediumButtonGroup_NacaReference);
            app.AirButton_NacaReference.Text = 'Air';
            app.CustomProfilePanel = uipanel(app.UIFigure);
            app.CustomProfilePanel.Title = 'Custom Profile';
            app.FilePathEditFieldLabel = uilabel(app.CustomProfilePanel);
            app.FilePathEditFieldLabel.Text = 'FilePath';
            app.FilePathEditField_CustomProfile = uieditfield(app.CustomProfilePanel, 'text');
            app.CordLengthEditField_3Label = uilabel(app.CustomProfilePanel);
            app.CordLengthEditField_3Label.Text = 'Cord Length';
            app.CordLengthEditField_CustomProfile = uieditfield(app.CustomProfilePanel, 'numeric');
            app.LoadProfileButton = uibutton(app.CustomProfilePanel, 'push');
            app.LoadProfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadProfileButtonPushed, true);
            app.LoadProfileButton.Text = 'Load Profile';
            app.UIAxes_CustomProfile = uiaxes(app.CustomProfilePanel);
            title(app.UIAxes_CustomProfile, 'Custom Profile')
            xlabel(app.UIAxes_CustomProfile, 'X')
            ylabel(app.UIAxes_CustomProfile, 'Y')
            app.MediumButtonGroup_CustomProfile = uibuttongroup(app.CustomProfilePanel);
            app.MediumButtonGroup_CustomProfile.Title = 'Medium';
            app.WaterButton_CustomProfile = uiradiobutton(app.MediumButtonGroup_CustomProfile);
            app.WaterButton_CustomProfile.Text = 'Water';
            app.WaterButton_CustomProfile.Value = true;
            app.AirButton_CustomProfile = uiradiobutton(app.MediumButtonGroup_CustomProfile);
            app.AirButton_CustomProfile.Text = 'Air';
            app.AddAirfoilButton_CustomProfile = uibutton(app.CustomProfilePanel, 'push');
            app.AddAirfoilButton_CustomProfile.ButtonPushedFcn = createCallbackFcn(app, @AddAirfoilButton_CustomProfilePushed, true);
            app.AddAirfoilButton_CustomProfile.Enable = 'off';
            app.AddAirfoilButton_CustomProfile.Text = 'Add Airfoil';
            app.ReynoldsNumberLabel_2 = uilabel(app.CustomProfilePanel);
            app.ReynoldsNumberLabel_2.Text = 'Reynolds Number';
            app.ReynoldsNumberEditField_CustomAirfoil = uieditfield(app.CustomProfilePanel, 'numeric');
            app.DeleteAirfoilButton_CustomProfile = uibutton(app.CustomProfilePanel, 'push');
            app.DeleteAirfoilButton_CustomProfile.ButtonPushedFcn = createCallbackFcn(app, @DeleteAirfoilButton_CustomProfilePushed, true);
            app.DeleteAirfoilButton_CustomProfile.Text = 'Delete Airfoil';
            app.StandardAirfoilPanel = uipanel(app.UIFigure);
            app.StandardAirfoilPanel.Title = 'Standard Airfoil';
            app.DropDown_StandardAirfoil = uidropdown(app.StandardAirfoilPanel);
            app.DropDown_StandardAirfoil.Items = {'NACA 0015', 'NACA 0018', 'NACA 2412'};
            app.DropDown_StandardAirfoil.Value = 'NACA 0015';
            app.CordLengthEditField_4Label = uilabel(app.StandardAirfoilPanel);
            app.CordLengthEditField_4Label.Text = 'Cord Length';
            app.CordLengthEditField_StandardAirfoil = uieditfield(app.StandardAirfoilPanel, 'numeric');
            app.MediumButtonGroup_StandardAirfoil = uibuttongroup(app.StandardAirfoilPanel);
            app.MediumButtonGroup_StandardAirfoil.Title = 'Medium';
            app.WaterButton_StandardAirfoil = uiradiobutton(app.MediumButtonGroup_StandardAirfoil);
            app.WaterButton_StandardAirfoil.Text = 'Water';
            app.WaterButton_StandardAirfoil.Value = true;
            app.AirButton_StandardAirfoil = uiradiobutton(app.MediumButtonGroup_StandardAirfoil);
            app.AirButton_StandardAirfoil.Text = 'Air';
            app.AirfoiltypeLabel = uilabel(app.StandardAirfoilPanel);
            app.AirfoiltypeLabel.Text = 'Airfoil type';
            app.StandardAirfoil_AddAirfoilButton = uibutton(app.StandardAirfoilPanel, 'push');
            app.StandardAirfoil_AddAirfoilButton.ButtonPushedFcn = createCallbackFcn(app, @StandardAirfoil_AddAirfoilButtonPushed, true);
            app.StandardAirfoil_AddAirfoilButton.Text = 'Add Airfoil';
            app.ReynoldsNumberLabel = uilabel(app.StandardAirfoilPanel);
            app.ReynoldsNumberLabel.Text = 'Reynolds Number';
            app.ReynoldsNumberEditField_StandardAirfoil = uieditfield(app.StandardAirfoilPanel, 'numeric');
            app.ProfilePowerCurvesComparerLabel = uilabel(app.UIFigure);
            app.ProfilePowerCurvesComparerLabel.Text = 'Profile Power Curves Comparer';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'ID'; 'Airfoil'; 'Cord Length'; 'Reynolds'; 'Medium'};
            app.UITable.RowName = {''; ''};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.DeleteElementButton = uibutton(app.UIFigure, 'push');
            app.DeleteElementButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteElementButtonPushed, true);
            app.DeleteElementButton.Text = 'Delete Element';
            app.ClearTableButton = uibutton(app.UIFigure, 'push');
            app.ClearTableButton.ButtonPushedFcn = createCallbackFcn(app, @ClearTableButtonPushed, true);
            app.ClearTableButton.Text = 'Clear Table';
            app.HidecurveButton = uibutton(app.UIFigure, 'push');
            app.HidecurveButton.Text = 'Hide curve';
            app.PlotcurveButton = uibutton(app.UIFigure, 'push');
            app.PlotcurveButton.Text = 'Plot curve';
            app.StatusEditFieldLabel = uilabel(app.UIFigure);
            app.StatusEditFieldLabel.Text = 'Status';
            app.StatusEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = guiApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end